classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        ControlTab                      matlab.ui.container.Tab
        OperationModeButtonGroup        matlab.ui.container.ButtonGroup
        ManualButton                    matlab.ui.control.RadioButton
        AutomaticButton                 matlab.ui.control.RadioButton
        PDControllerGainsPanel          matlab.ui.container.Panel
        RollControlLabel                matlab.ui.control.Label
        PitchControlLabel               matlab.ui.control.Label
        ProportionalGainEditFieldLabel  matlab.ui.control.Label
        ProportionalGainEditFieldRoll   matlab.ui.control.NumericEditField
        TimeConstantEditFieldLabel      matlab.ui.control.Label
        TimeConstantEditFieldRoll       matlab.ui.control.NumericEditField
        DerivativeGainEditFieldLabel    matlab.ui.control.Label
        DerivativeGainEditFieldRoll     matlab.ui.control.NumericEditField
        AltitudeControlLabel            matlab.ui.control.Label
        TimeConstantEditField_2Label    matlab.ui.control.Label
        TimeConstantEditFieldPitch      matlab.ui.control.NumericEditField
        DerivativeGainEditField_2Label  matlab.ui.control.Label
        DerivativeGainEditFieldPitch    matlab.ui.control.NumericEditField
        ProportionalGainEditField_2Label  matlab.ui.control.Label
        ProportionalGainEditFieldPitch  matlab.ui.control.NumericEditField
        TimeConstantEditField_3Label    matlab.ui.control.Label
        TimeConstantEditFieldAltitude   matlab.ui.control.NumericEditField
        DerivativeGainEditField_3Label  matlab.ui.control.Label
        DerivativeGainEditFieldAltitude  matlab.ui.control.NumericEditField
        ProportionalGainEditField_3Label  matlab.ui.control.Label
        ProportionalGainEditFieldAltitude  matlab.ui.control.NumericEditField
        MotorControlPanel               matlab.ui.container.Panel
        AllSliderLabel                  matlab.ui.control.Label
        AllSlider                       matlab.ui.control.Slider
        Motor3SliderLabel               matlab.ui.control.Label
        Motor3Slider                    matlab.ui.control.Slider
        Motor1SliderLabel               matlab.ui.control.Label
        Motor1Slider                    matlab.ui.control.Slider
        Motor2SliderLabel               matlab.ui.control.Label
        Motor2Slider                    matlab.ui.control.Slider
        MotorConfigurationPanel         matlab.ui.container.Panel
        MaxMotorVoltageEditFieldLabel   matlab.ui.control.Label
        MaxMotorVoltageEditField        matlab.ui.control.NumericEditField
        InitialCenterofMassPositionPanel  matlab.ui.container.Panel
        CoMPositionEditFieldLabel       matlab.ui.control.Label
        CoMPositionEditFieldX           matlab.ui.control.NumericEditField
        CoMPositionEditFieldY           matlab.ui.control.NumericEditField
        CoMPositionEditFieldZ           matlab.ui.control.NumericEditField
        CameraControlTab                matlab.ui.container.Tab
        CameraSelectionButtonGroup      matlab.ui.container.ButtonGroup
        BottomCameraAButton             matlab.ui.control.RadioButton
        BottomCameraBButton             matlab.ui.control.RadioButton
        SideCameraPanel                 matlab.ui.container.Panel
        ROIHoldPositionpxEditFieldLabel  matlab.ui.control.Label
        ROIHoldPositionpxEditFieldSideRow  matlab.ui.control.NumericEditField
        ROIHoldPositionpxEditFieldSideCol  matlab.ui.control.NumericEditField
        HoldReleaseButtonSide           matlab.ui.control.Button
        ROISizeEditFieldLabel           matlab.ui.control.Label
        ROISizeEditFieldSideRow         matlab.ui.control.NumericEditField
        ROISizeEditFieldSideCol         matlab.ui.control.NumericEditField
        ImageDimensionsEditFieldLabel   matlab.ui.control.Label
        ImageDimensionsEditFieldSideRow  matlab.ui.control.NumericEditField
        ImageDimensionsEditFieldSideCol  matlab.ui.control.NumericEditField
        CrosshairWidthEditFieldLabel    matlab.ui.control.Label
        CrosshairWidthEditFieldSide     matlab.ui.control.NumericEditField
        VideoDecimationEditFieldLabel   matlab.ui.control.Label
        VideoDecimationEditFieldSide    matlab.ui.control.NumericEditField
        ImageWidthEditFieldLabel        matlab.ui.control.Label
        ImageWidthEditFieldSide         matlab.ui.control.NumericEditField
        BottomCameraPanel               matlab.ui.container.Panel
        ROIHoldPositionpxEditFieldLabel_2  matlab.ui.control.Label
        ROIHoldPositionpxEditFieldBottomARow  matlab.ui.control.NumericEditField
        ROIHoldPositionpxEditFieldBottomACol  matlab.ui.control.NumericEditField
        HoldReleaseButtonBottomA        matlab.ui.control.Button
        ROISizeEditField_5Label         matlab.ui.control.Label
        ROISizeEditFieldBottomARow      matlab.ui.control.NumericEditField
        ROISizeEditFieldBottomACol      matlab.ui.control.NumericEditField
        ImageDimensionsEditField_2Label  matlab.ui.control.Label
        ImageDimensionsEditFieldBottomRow  matlab.ui.control.NumericEditField
        ImageDimensionsEditFieldBottomCol  matlab.ui.control.NumericEditField
        CrosshairWidthEditField_2Label  matlab.ui.control.Label
        CrosshairWidthEditFieldBottomA  matlab.ui.control.NumericEditField
        VideoDecimationEditField_2Label  matlab.ui.control.Label
        VideoDecimationEditFieldBottom  matlab.ui.control.NumericEditField
        ImageWidthEditField_2Label      matlab.ui.control.Label
        ImageWidthEditFieldBottom       matlab.ui.control.NumericEditField
        BottomCameraALabel              matlab.ui.control.Label
        BottomCameraBLabel              matlab.ui.control.Label
        ROIHoldPositionpxEditFieldBottomBCol  matlab.ui.control.NumericEditField
        HoldReleaseButtonBottomB        matlab.ui.control.Button
        ROISizeEditFieldBottomBCol      matlab.ui.control.NumericEditField
        ROIHoldPositionpxEditFieldLabel_4  matlab.ui.control.Label
        ROIHoldPositionpxEditFieldBottomBRow  matlab.ui.control.NumericEditField
        ROISizeEditField_12Label        matlab.ui.control.Label
        ROISizeEditFieldBottomBRow      matlab.ui.control.NumericEditField
        CrosshairWidthEditField_4Label  matlab.ui.control.Label
        CrosshairWidthEditFieldBottomB  matlab.ui.control.NumericEditField
        BothCamerasLabel                matlab.ui.control.Label
        SlantCameraPanel                matlab.ui.container.Panel
        ROIHoldPositionpxEditFieldLabel_3  matlab.ui.control.Label
        ROIHoldPositionpxEditFieldSlantRow  matlab.ui.control.NumericEditField
        ROIHoldPositionpxEditFieldSlantCol  matlab.ui.control.NumericEditField
        HoldReleaseButtonSideSlant      matlab.ui.control.Button
        ROISizeEditField_8Label         matlab.ui.control.Label
        ROISizeEditFieldSlantRow        matlab.ui.control.NumericEditField
        ROISizeEditFieldSlantCol        matlab.ui.control.NumericEditField
        ImageDimensionsEditField_3Label  matlab.ui.control.Label
        ImageDimensionsEditFieldSlantRow  matlab.ui.control.NumericEditField
        ImageDimensionsEditFieldSlantCol  matlab.ui.control.NumericEditField
        CrosshairWidthEditField_3Label  matlab.ui.control.Label
        CrosshairWidthEditFieldSlant    matlab.ui.control.NumericEditField
        VideoDecimationEditField_3Label  matlab.ui.control.Label
        VideoDecimationEditFieldSlant   matlab.ui.control.NumericEditField
        ImageWidthEditField_3Label      matlab.ui.control.Label
        ImageWidthEditFieldSlant        matlab.ui.control.NumericEditField
        CameraSettingsTab               matlab.ui.container.Tab
        DotPropertiesPanel              matlab.ui.container.Panel
        SideDotSeparationDistanceEditField_2Label  matlab.ui.control.Label
        SideDotSeparationDistanceEditField  matlab.ui.control.NumericEditField
        SideDotPositionVectorEditFieldLabel_2  matlab.ui.control.Label
        SideDotPositionVectorEditFieldX  matlab.ui.control.NumericEditField
        BottomADotSeparationDistanceEditField_2Label  matlab.ui.control.Label
        BottomADotSeparationDistanceEditField  matlab.ui.control.NumericEditField
        BottomADotPositionVectorEditFieldLabel_2  matlab.ui.control.Label
        BottomADotPositionVectorEditFieldX  matlab.ui.control.NumericEditField
        BottomBDotSeparationDistanceEditField_2Label  matlab.ui.control.Label
        BottomBDotSeparationDistanceEditField  matlab.ui.control.NumericEditField
        BottomBDotPositionValuieEditFieldLabel_2  matlab.ui.control.Label
        BottomBDotPositionValuieEditFieldX  matlab.ui.control.NumericEditField
        SideDotPositionVectorEditFieldY  matlab.ui.control.NumericEditField
        SideDotPositionVectorEditFieldZ  matlab.ui.control.NumericEditField
        BottomADotPositionVectorEditFieldY  matlab.ui.control.NumericEditField
        BottomADotPositionVectorEditFieldZ  matlab.ui.control.NumericEditField
        BottomBDotPositionValuieEditFieldY  matlab.ui.control.NumericEditField
        BottomBDotPositionValuieEditFieldZ  matlab.ui.control.NumericEditField
        CameraSpecificationsPanel       matlab.ui.container.Panel
        CameraFOVEditFieldLabel_2       matlab.ui.control.Label
        CameraFOVEditFieldHor           matlab.ui.control.NumericEditField
        CameraFOVEditFieldVer           matlab.ui.control.NumericEditField
        ImageErrorSettingsPanel         matlab.ui.container.Panel
        ThresholdEditField_2Label       matlab.ui.control.Label
        ThresholdEditField              matlab.ui.control.NumericEditField
        DebounceTimeEditField_2Label    matlab.ui.control.Label
        DebounceTimeEditField           matlab.ui.control.NumericEditField
        CameraOrientationPanel          matlab.ui.container.Panel
        SideCameraLabel2                matlab.ui.control.Label
        EulerAngleEditFieldLabel_2      matlab.ui.control.Label
        EulerAngleEditFieldSideRoll     matlab.ui.control.NumericEditField
        BottomCameraLabel               matlab.ui.control.Label
        EulerAngleEditField_2Label_2    matlab.ui.control.Label
        EulerAngleEditFieldBottomRoll   matlab.ui.control.NumericEditField
        EulerAngleEditFieldBottomPitch  matlab.ui.control.NumericEditField
        EulerAngleEditFieldBottomYaw    matlab.ui.control.NumericEditField
        PositionEditField_8Label_2      matlab.ui.control.Label
        PositionEditFieldBottomX        matlab.ui.control.NumericEditField
        PositionEditFieldBottomY        matlab.ui.control.NumericEditField
        PositionEditFieldBottomZ        matlab.ui.control.NumericEditField
        SlantCameraLabel2               matlab.ui.control.Label
        EulerAngleEditField_3Label_2    matlab.ui.control.Label
        EulerAngleEditFieldSlantRoll    matlab.ui.control.NumericEditField
        PositionEditField_11Label_2     matlab.ui.control.Label
        PositionEditFieldSlantX         matlab.ui.control.NumericEditField
        PositionEditFieldSlantY         matlab.ui.control.NumericEditField
        EulerAngleEditFieldSlantPitch   matlab.ui.control.NumericEditField
        EulerAngleEditFieldSlantYaw     matlab.ui.control.NumericEditField
        PositionEditFieldSlantZ         matlab.ui.control.NumericEditField
        PositionEditField_16Label_2     matlab.ui.control.Label
        PositionEditFieldSideX          matlab.ui.control.NumericEditField
        EulerAngleEditFieldSidePitch    matlab.ui.control.NumericEditField
        EulerAngleEditFieldSideYaw      matlab.ui.control.NumericEditField
        PositionEditFieldSideY          matlab.ui.control.NumericEditField
        PositionEditFieldSideZ          matlab.ui.control.NumericEditField
        CommunicationTab                matlab.ui.container.Tab
        UDPPTPConfigurationPanel        matlab.ui.container.Panel
        PTPOffsetEditFieldLabel         matlab.ui.control.Label
        PTPOffsetEditField              matlab.ui.control.NumericEditField
        SyncIntervalEditFieldLabel      matlab.ui.control.Label
        SyncIntervalEditField           matlab.ui.control.NumericEditField
        Machine3Panel_2                 matlab.ui.container.Panel
        IPAddressEditFieldLabel         matlab.ui.control.Label
        IPAddressEditFieldMachine2      matlab.ui.control.NumericEditField
        PortNumberEditFieldLabel        matlab.ui.control.Label
        PortNumberEditFieldMachine2     matlab.ui.control.NumericEditField
        MinDelayEditFieldLabel          matlab.ui.control.Label
        MinDelayEditFieldMachine2       matlab.ui.control.NumericEditField
        Machine4Panel_2                 matlab.ui.container.Panel
        IPAddressEditField_2Label       matlab.ui.control.Label
        IPAddressEditFieldMachine3      matlab.ui.control.NumericEditField
        PortNumberEditField_2Label      matlab.ui.control.Label
        PortNumberEditFieldMachine3     matlab.ui.control.NumericEditField
        MinDelayEditField_2Label        matlab.ui.control.Label
        MinDelayEditFieldMachine3       matlab.ui.control.NumericEditField
        Machine5Panel                   matlab.ui.container.Panel
        IPAddressEditField_3Label       matlab.ui.control.Label
        IPAddressEditFieldMachine4      matlab.ui.control.NumericEditField
        PortNumberEditField_3Label      matlab.ui.control.Label
        PortNumberEditFieldMachine4     matlab.ui.control.NumericEditField
        MinDelayEditField_3Label        matlab.ui.control.Label
        MinDelayEditFieldMachine4       matlab.ui.control.NumericEditField
        Machine2Panel                   matlab.ui.container.Panel
        AnnounceIntervalEditFieldLabel  matlab.ui.control.Label
        AnnounceIntervalEditField       matlab.ui.control.NumericEditField
    end


    methods (Access = private)

        % Value changed function: ProportionalGainEditFieldRoll
        function ProportionalGainEditFieldRollValueChanged(app, event)
            value = app.ProportionalGainEditFieldRoll.Value;
            global params;
            params.rollCtrlkp_Prad.Value = value;
            %set_param(gcs,'SimulationCommand','Update');
        end

        % Value changed function: DerivativeGainEditFieldRoll
        function DerivativeGainEditFieldRollValueChanged(app, event)
            value = app.DerivativeGainEditFieldRoll.Value;
            global params;
            params.rollCtrlkd_sPrad.Value = value;
            %set_param(gcs,'SimulationCommand','Update');
        end

        % Value changed function: TimeConstantEditFieldRoll
        function TimeConstantEditFieldRollValueChanged(app, event)
            value = app.TimeConstantEditFieldRoll.Value;
            global params;
            params.rollCtrltau_s.Value = value;
            %set_param(gcs,'SimulationCommand','Update');
        end

        % Value changed function: ProportionalGainEditFieldPitch
        function ProportionalGainEditFieldPitchValueChanged(app, event)
            value = app.ProportionalGainEditFieldPitch.Value;
            global params;
            params.pitchCtrlkp_Prad.Value = value;
            %set_param(gcs,'SimulationCommand','Update');
        end

        % Value changed function: DerivativeGainEditFieldPitch
        function DerivativeGainEditFieldPitchValueChanged(app, event)
            value = app.DerivativeGainEditFieldPitch.Value;
            global params;
            params.pitchCtrlkd_sPrad.Value = value;
            %set_param(gcs,'SimulationCommand','Update');
        end

        % Value changed function: TimeConstantEditFieldPitch
        function TimeConstantEditFieldPitchValueChanged(app, event)
            value = app.TimeConstantEditFieldPitch.Value;
            global params;
            params.pitchCtrltau_s.Value = value;
            %set_param(gcs,'SimulationCommand','Update');
        end

        % Value changed function: ProportionalGainEditFieldAltitude
        function ProportionalGainEditFieldAltitudeValueChanged(app, event)
            value = app.ProportionalGainEditFieldAltitude.Value;
            global params;
            params.altCtrlkp_Prad.Value = value;
            %set_param(gcs,'SimulationCommand','Update');
        end

        % Value changed function: DerivativeGainEditFieldAltitude
        function DerivativeGainEditFieldAltitudeValueChanged(app, event)
            value = app.DerivativeGainEditFieldAltitude.Value;
            global params;
            params.altCtrlkd_sPrad.Value = value;
            %set_param(gcs,'SimulationCommand','Update');
        end

        % Value changed function: TimeConstantEditFieldAltitude
        function TimeConstantEditFieldAltitudeValueChanged(app, event)
            value = app.TimeConstantEditFieldAltitude.Value;
            global params;
            params.altCtrltau_s.Value = value;
            %set_param(gcs,'SimulationCommand','Update');
        end

        % Value changed function: Motor1Slider
        function Motor1SliderValueChanged(app, event)
            value = app.Motor1Slider.Value;
            global params;
            params.mtr1ManulaSpdCmd_na.Value = value;
            %set_param(gcs,'SimulationCommand','Update');
        end

        % Value changed function: Motor2Slider
        function Motor2SliderValueChanged(app, event)
            value = app.Motor2Slider.Value;
            global params;
            params.mtr2ManulaSpdCmd_na.Value = value;
            %set_param(gcs,'SimulationCommand','Update');
        end

        % Value changed function: Motor3Slider
        function Motor3SliderValueChanged(app, event)
            value = app.Motor3Slider.Value;
            global params;
            params.mtr3ManulaSpdCmd_na.Value = value;
            %set_param(gcs,'SimulationCommand','Update');
        end

        % Value changed function: MaxMotorVoltageEditField
        function MaxMotorVoltageEditFieldValueChanged(app, event)
            value = app.MaxMotorVoltageEditField.Value;
            global params;
            params.maxOutVltg_vlt.Value = value;
            %set_param(gcs,'SimulationCommand','Update');
        end

        % Value changed function: CoMPositionEditFieldX
        function CoMPositionEditFieldXValueChanged(app, event)
            value = app.CoMPositionEditFieldX.Value;
            global params;
            params.initCoMPosVec_cm.Value(1) = value;
            %set_param(gcs,'SimulationCommand','Update');
        end

        % Value changed function: AllSlider
        function AllSliderValueChanged(app, event)
            value = app.AllSlider.Value;
            global params;
            params.mtr1ManulaSpdCmd_na.Value = value;
            params.mtr2ManulaSpdCmd_na.Value = value;
            params.mtr3ManulaSpdCmd_na.Value = value;
            %set_param(gcs,'SimulationCommand','Update');
        end

        % Value changed function: CoMPositionEditFieldY
        function CoMPositionEditFieldYValueChanged(app, event)
            value = app.CoMPositionEditFieldY.Value;
            global params;
            params.initCoMPosVec_cm.Value(2) = value;
            %set_param(gcs,'SimulationCommand','Update');
        end

        % Value changed function: CoMPositionEditFieldZ
        function CoMPositionEditFieldZValueChanged(app, event)
            value = app.CoMPositionEditFieldZ.Value;
            global params;
            params.initCoMPosVec_cm.Value(3) = value;
            %set_param(gcs,'SimulationCommand','Update');
        end

        % Value changed function: CameraFOVEditFieldHor
        function CameraFOVEditFieldHorValueChanged(app, event)
            value = app.CameraFOVEditFieldHor.Value;
            global params;
            params.cameraFOV_deg.Value(1) = value;
        end

        % Value changed function: CameraFOVEditFieldVer
        function CameraFOVEditFieldVerValueChanged(app, event)
            value = app.CameraFOVEditFieldVer.Value;
            global params;
            params.cameraFOV_deg.Value(2) = value;
        end

        % Value changed function: PositionEditFieldSideX
        function PositionEditFieldSideXValueChanged(app, event)
            value = app.PositionEditFieldSideX.Value;
            global params;
            params.sideCamPosVec_cm.Value(1) = value;
        end

        % Value changed function: PositionEditFieldSideY
        function PositionEditFieldSideYValueChanged(app, event)
            value = app.PositionEditFieldSideY.Value;
            global params;
            params.sideCamPosVec_cm.Value(2) = value;
        end

        % Value changed function: PositionEditFieldSideZ
        function PositionEditFieldSideZValueChanged(app, event)
            value = app.PositionEditFieldSideZ.Value;
            global params;
            params.sideCamPosVec_cm.Value(3) = value;
        end

        % Value changed function: EulerAngleEditFieldSideRoll
        function EulerAngleEditFieldSideRollValueChanged(app, event)
            value = app.EulerAngleEditFieldSideRoll.Value;
            global params;
            params.sideCamEulAng_deg.Value(1) = value;
        end

        % Value changed function: EulerAngleEditFieldSidePitch
        function EulerAngleEditFieldSidePitchValueChanged(app, event)
            value = app.EulerAngleEditFieldSidePitch.Value;
            global params;
            params.sideCamEulAng_deg.Value(2) = value;
        end

        % Value changed function: EulerAngleEditFieldSideYaw
        function EulerAngleEditFieldSideYawValueChanged(app, event)
            value = app.EulerAngleEditFieldSideYaw.Value;
            global params;
            params.sideCamEulAng_deg.Value(3) = value;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ControlTab = uitab(app.TabGroup);
            app.ControlTab.Title = 'Control';
            app.OperationModeButtonGroup = uibuttongroup(app.ControlTab);
            app.OperationModeButtonGroup.Title = 'Operation Mode';
            app.ManualButton = uiradiobutton(app.OperationModeButtonGroup);
            app.ManualButton.Text = 'Manual';
            app.ManualButton.Value = true;
            app.AutomaticButton = uiradiobutton(app.OperationModeButtonGroup);
            app.AutomaticButton.Text = 'Automatic';
            app.PDControllerGainsPanel = uipanel(app.ControlTab);
            app.PDControllerGainsPanel.Title = 'PD Controller Gains';
            app.RollControlLabel = uilabel(app.PDControllerGainsPanel);
            app.RollControlLabel.Text = 'Roll Control';
            app.PitchControlLabel = uilabel(app.PDControllerGainsPanel);
            app.PitchControlLabel.Text = 'Pitch Control';
            app.ProportionalGainEditFieldLabel = uilabel(app.PDControllerGainsPanel);
            app.ProportionalGainEditFieldLabel.Text = 'Proportional Gain';
            app.ProportionalGainEditFieldRoll = uieditfield(app.PDControllerGainsPanel, 'numeric');
            app.ProportionalGainEditFieldRoll.ValueChangedFcn = createCallbackFcn(app, @ProportionalGainEditFieldRollValueChanged, true);
            app.TimeConstantEditFieldLabel = uilabel(app.PDControllerGainsPanel);
            app.TimeConstantEditFieldLabel.Text = 'Time Constant';
            app.TimeConstantEditFieldRoll = uieditfield(app.PDControllerGainsPanel, 'numeric');
            app.TimeConstantEditFieldRoll.ValueChangedFcn = createCallbackFcn(app, @TimeConstantEditFieldRollValueChanged, true);
            app.DerivativeGainEditFieldLabel = uilabel(app.PDControllerGainsPanel);
            app.DerivativeGainEditFieldLabel.Text = 'Derivative Gain';
            app.DerivativeGainEditFieldRoll = uieditfield(app.PDControllerGainsPanel, 'numeric');
            app.DerivativeGainEditFieldRoll.ValueChangedFcn = createCallbackFcn(app, @DerivativeGainEditFieldRollValueChanged, true);
            app.AltitudeControlLabel = uilabel(app.PDControllerGainsPanel);
            app.AltitudeControlLabel.Text = 'Altitude Control';
            app.TimeConstantEditField_2Label = uilabel(app.PDControllerGainsPanel);
            app.TimeConstantEditField_2Label.Text = 'Time Constant';
            app.TimeConstantEditFieldPitch = uieditfield(app.PDControllerGainsPanel, 'numeric');
            app.TimeConstantEditFieldPitch.ValueChangedFcn = createCallbackFcn(app, @TimeConstantEditFieldPitchValueChanged, true);
            app.DerivativeGainEditField_2Label = uilabel(app.PDControllerGainsPanel);
            app.DerivativeGainEditField_2Label.Text = 'Derivative Gain';
            app.DerivativeGainEditFieldPitch = uieditfield(app.PDControllerGainsPanel, 'numeric');
            app.DerivativeGainEditFieldPitch.ValueChangedFcn = createCallbackFcn(app, @DerivativeGainEditFieldPitchValueChanged, true);
            app.ProportionalGainEditField_2Label = uilabel(app.PDControllerGainsPanel);
            app.ProportionalGainEditField_2Label.Text = 'Proportional Gain';
            app.ProportionalGainEditFieldPitch = uieditfield(app.PDControllerGainsPanel, 'numeric');
            app.ProportionalGainEditFieldPitch.ValueChangedFcn = createCallbackFcn(app, @ProportionalGainEditFieldPitchValueChanged, true);
            app.TimeConstantEditField_3Label = uilabel(app.PDControllerGainsPanel);
            app.TimeConstantEditField_3Label.Text = 'Time Constant';
            app.TimeConstantEditFieldAltitude = uieditfield(app.PDControllerGainsPanel, 'numeric');
            app.TimeConstantEditFieldAltitude.ValueChangedFcn = createCallbackFcn(app, @TimeConstantEditFieldAltitudeValueChanged, true);
            app.DerivativeGainEditField_3Label = uilabel(app.PDControllerGainsPanel);
            app.DerivativeGainEditField_3Label.Text = 'Derivative Gain';
            app.DerivativeGainEditFieldAltitude = uieditfield(app.PDControllerGainsPanel, 'numeric');
            app.DerivativeGainEditFieldAltitude.ValueChangedFcn = createCallbackFcn(app, @DerivativeGainEditFieldAltitudeValueChanged, true);
            app.ProportionalGainEditField_3Label = uilabel(app.PDControllerGainsPanel);
            app.ProportionalGainEditField_3Label.Text = 'Proportional Gain';
            app.ProportionalGainEditFieldAltitude = uieditfield(app.PDControllerGainsPanel, 'numeric');
            app.ProportionalGainEditFieldAltitude.ValueChangedFcn = createCallbackFcn(app, @ProportionalGainEditFieldAltitudeValueChanged, true);
            app.MotorControlPanel = uipanel(app.ControlTab);
            app.MotorControlPanel.Title = 'Motor Control';
            app.AllSliderLabel = uilabel(app.MotorControlPanel);
            app.AllSliderLabel.Text = 'All';
            app.AllSlider = uislider(app.MotorControlPanel);
            app.AllSlider.Limits = [-1 1];
            app.AllSlider.ValueChangedFcn = createCallbackFcn(app, @AllSliderValueChanged, true);
            app.Motor3SliderLabel = uilabel(app.MotorControlPanel);
            app.Motor3SliderLabel.Text = 'Motor 3';
            app.Motor3Slider = uislider(app.MotorControlPanel);
            app.Motor3Slider.Limits = [-1 1];
            app.Motor3Slider.ValueChangedFcn = createCallbackFcn(app, @Motor3SliderValueChanged, true);
            app.Motor1SliderLabel = uilabel(app.MotorControlPanel);
            app.Motor1SliderLabel.Text = 'Motor 1';
            app.Motor1Slider = uislider(app.MotorControlPanel);
            app.Motor1Slider.Limits = [-1 1];
            app.Motor1Slider.ValueChangedFcn = createCallbackFcn(app, @Motor1SliderValueChanged, true);
            app.Motor2SliderLabel = uilabel(app.MotorControlPanel);
            app.Motor2SliderLabel.Text = 'Motor 2';
            app.Motor2Slider = uislider(app.MotorControlPanel);
            app.Motor2Slider.Limits = [-1 1];
            app.Motor2Slider.ValueChangedFcn = createCallbackFcn(app, @Motor2SliderValueChanged, true);
            app.MotorConfigurationPanel = uipanel(app.ControlTab);
            app.MotorConfigurationPanel.Title = 'Motor Configuration';
            app.MaxMotorVoltageEditFieldLabel = uilabel(app.MotorConfigurationPanel);
            app.MaxMotorVoltageEditFieldLabel.Text = 'Max Motor Voltage';
            app.MaxMotorVoltageEditField = uieditfield(app.MotorConfigurationPanel, 'numeric');
            app.MaxMotorVoltageEditField.ValueChangedFcn = createCallbackFcn(app, @MaxMotorVoltageEditFieldValueChanged, true);
            app.InitialCenterofMassPositionPanel = uipanel(app.ControlTab);
            app.InitialCenterofMassPositionPanel.Title = 'Initial Center of Mass Position';
            app.CoMPositionEditFieldLabel = uilabel(app.InitialCenterofMassPositionPanel);
            app.CoMPositionEditFieldLabel.Text = 'CoM Position';
            app.CoMPositionEditFieldX = uieditfield(app.InitialCenterofMassPositionPanel, 'numeric');
            app.CoMPositionEditFieldX.ValueChangedFcn = createCallbackFcn(app, @CoMPositionEditFieldXValueChanged, true);
            app.CoMPositionEditFieldY = uieditfield(app.InitialCenterofMassPositionPanel, 'numeric');
            app.CoMPositionEditFieldY.ValueChangedFcn = createCallbackFcn(app, @CoMPositionEditFieldYValueChanged, true);
            app.CoMPositionEditFieldZ = uieditfield(app.InitialCenterofMassPositionPanel, 'numeric');
            app.CoMPositionEditFieldZ.ValueChangedFcn = createCallbackFcn(app, @CoMPositionEditFieldZValueChanged, true);
            app.CameraControlTab = uitab(app.TabGroup);
            app.CameraControlTab.Title = 'Camera Control';
            app.CameraSelectionButtonGroup = uibuttongroup(app.CameraControlTab);
            app.CameraSelectionButtonGroup.Title = 'Camera Selection';
            app.BottomCameraAButton = uiradiobutton(app.CameraSelectionButtonGroup);
            app.BottomCameraAButton.Text = 'Bottom Camera A';
            app.BottomCameraAButton.Value = true;
            app.BottomCameraBButton = uiradiobutton(app.CameraSelectionButtonGroup);
            app.BottomCameraBButton.Text = 'Bottom Camera B';
            app.SideCameraPanel = uipanel(app.CameraControlTab);
            app.SideCameraPanel.Title = 'Side Camera';
            app.ROIHoldPositionpxEditFieldLabel = uilabel(app.SideCameraPanel);
            app.ROIHoldPositionpxEditFieldLabel.Text = 'ROI Hold Position (px)';
            app.ROIHoldPositionpxEditFieldSideRow = uieditfield(app.SideCameraPanel, 'numeric');
            app.ROIHoldPositionpxEditFieldSideCol = uieditfield(app.SideCameraPanel, 'numeric');
            app.HoldReleaseButtonSide = uibutton(app.SideCameraPanel, 'push');
            app.HoldReleaseButtonSide.Text = 'Hold/Release';
            app.ROISizeEditFieldLabel = uilabel(app.SideCameraPanel);
            app.ROISizeEditFieldLabel.Text = 'ROI Size';
            app.ROISizeEditFieldSideRow = uieditfield(app.SideCameraPanel, 'numeric');
            app.ROISizeEditFieldSideCol = uieditfield(app.SideCameraPanel, 'numeric');
            app.ImageDimensionsEditFieldLabel = uilabel(app.SideCameraPanel);
            app.ImageDimensionsEditFieldLabel.Text = 'Image Dimensions';
            app.ImageDimensionsEditFieldSideRow = uieditfield(app.SideCameraPanel, 'numeric');
            app.ImageDimensionsEditFieldSideCol = uieditfield(app.SideCameraPanel, 'numeric');
            app.CrosshairWidthEditFieldLabel = uilabel(app.SideCameraPanel);
            app.CrosshairWidthEditFieldLabel.Text = 'Crosshair Width';
            app.CrosshairWidthEditFieldSide = uieditfield(app.SideCameraPanel, 'numeric');
            app.VideoDecimationEditFieldLabel = uilabel(app.SideCameraPanel);
            app.VideoDecimationEditFieldLabel.Text = 'Video Decimation';
            app.VideoDecimationEditFieldSide = uieditfield(app.SideCameraPanel, 'numeric');
            app.ImageWidthEditFieldLabel = uilabel(app.SideCameraPanel);
            app.ImageWidthEditFieldLabel.Text = 'Image Width';
            app.ImageWidthEditFieldSide = uieditfield(app.SideCameraPanel, 'numeric');
            app.BottomCameraPanel = uipanel(app.CameraControlTab);
            app.BottomCameraPanel.Title = 'Bottom Camera';
            app.ROIHoldPositionpxEditFieldLabel_2 = uilabel(app.BottomCameraPanel);
            app.ROIHoldPositionpxEditFieldLabel_2.Text = 'ROI Hold Position (px)';
            app.ROIHoldPositionpxEditFieldBottomARow = uieditfield(app.BottomCameraPanel, 'numeric');
            app.ROIHoldPositionpxEditFieldBottomACol = uieditfield(app.BottomCameraPanel, 'numeric');
            app.HoldReleaseButtonBottomA = uibutton(app.BottomCameraPanel, 'push');
            app.HoldReleaseButtonBottomA.Text = 'Hold/Release';
            app.ROISizeEditField_5Label = uilabel(app.BottomCameraPanel);
            app.ROISizeEditField_5Label.Text = 'ROI Size';
            app.ROISizeEditFieldBottomARow = uieditfield(app.BottomCameraPanel, 'numeric');
            app.ROISizeEditFieldBottomACol = uieditfield(app.BottomCameraPanel, 'numeric');
            app.ImageDimensionsEditField_2Label = uilabel(app.BottomCameraPanel);
            app.ImageDimensionsEditField_2Label.Text = 'Image Dimensions';
            app.ImageDimensionsEditFieldBottomRow = uieditfield(app.BottomCameraPanel, 'numeric');
            app.ImageDimensionsEditFieldBottomCol = uieditfield(app.BottomCameraPanel, 'numeric');
            app.CrosshairWidthEditField_2Label = uilabel(app.BottomCameraPanel);
            app.CrosshairWidthEditField_2Label.Text = 'Crosshair Width';
            app.CrosshairWidthEditFieldBottomA = uieditfield(app.BottomCameraPanel, 'numeric');
            app.VideoDecimationEditField_2Label = uilabel(app.BottomCameraPanel);
            app.VideoDecimationEditField_2Label.Text = 'Video Decimation';
            app.VideoDecimationEditFieldBottom = uieditfield(app.BottomCameraPanel, 'numeric');
            app.ImageWidthEditField_2Label = uilabel(app.BottomCameraPanel);
            app.ImageWidthEditField_2Label.Text = 'Image Width';
            app.ImageWidthEditFieldBottom = uieditfield(app.BottomCameraPanel, 'numeric');
            app.BottomCameraALabel = uilabel(app.BottomCameraPanel);
            app.BottomCameraALabel.Text = 'Bottom Camera A';
            app.BottomCameraBLabel = uilabel(app.BottomCameraPanel);
            app.BottomCameraBLabel.Text = 'Bottom Camera B';
            app.ROIHoldPositionpxEditFieldBottomBCol = uieditfield(app.BottomCameraPanel, 'numeric');
            app.HoldReleaseButtonBottomB = uibutton(app.BottomCameraPanel, 'push');
            app.HoldReleaseButtonBottomB.Text = 'Hold/Release';
            app.ROISizeEditFieldBottomBCol = uieditfield(app.BottomCameraPanel, 'numeric');
            app.ROIHoldPositionpxEditFieldLabel_4 = uilabel(app.BottomCameraPanel);
            app.ROIHoldPositionpxEditFieldLabel_4.Text = 'ROI Hold Position (px)';
            app.ROIHoldPositionpxEditFieldBottomBRow = uieditfield(app.BottomCameraPanel, 'numeric');
            app.ROISizeEditField_12Label = uilabel(app.BottomCameraPanel);
            app.ROISizeEditField_12Label.Text = 'ROI Size';
            app.ROISizeEditFieldBottomBRow = uieditfield(app.BottomCameraPanel, 'numeric');
            app.CrosshairWidthEditField_4Label = uilabel(app.BottomCameraPanel);
            app.CrosshairWidthEditField_4Label.Text = 'Crosshair Width';
            app.CrosshairWidthEditFieldBottomB = uieditfield(app.BottomCameraPanel, 'numeric');
            app.BothCamerasLabel = uilabel(app.BottomCameraPanel);
            app.BothCamerasLabel.Text = 'Both Cameras';
            app.SlantCameraPanel = uipanel(app.CameraControlTab);
            app.SlantCameraPanel.Title = 'Slant Camera';
            app.ROIHoldPositionpxEditFieldLabel_3 = uilabel(app.SlantCameraPanel);
            app.ROIHoldPositionpxEditFieldLabel_3.Text = 'ROI Hold Position (px)';
            app.ROIHoldPositionpxEditFieldSlantRow = uieditfield(app.SlantCameraPanel, 'numeric');
            app.ROIHoldPositionpxEditFieldSlantCol = uieditfield(app.SlantCameraPanel, 'numeric');
            app.HoldReleaseButtonSideSlant = uibutton(app.SlantCameraPanel, 'push');
            app.HoldReleaseButtonSideSlant.Text = 'Hold/Release';
            app.ROISizeEditField_8Label = uilabel(app.SlantCameraPanel);
            app.ROISizeEditField_8Label.Text = 'ROI Size';
            app.ROISizeEditFieldSlantRow = uieditfield(app.SlantCameraPanel, 'numeric');
            app.ROISizeEditFieldSlantCol = uieditfield(app.SlantCameraPanel, 'numeric');
            app.ImageDimensionsEditField_3Label = uilabel(app.SlantCameraPanel);
            app.ImageDimensionsEditField_3Label.Text = 'Image Dimensions';
            app.ImageDimensionsEditFieldSlantRow = uieditfield(app.SlantCameraPanel, 'numeric');
            app.ImageDimensionsEditFieldSlantCol = uieditfield(app.SlantCameraPanel, 'numeric');
            app.CrosshairWidthEditField_3Label = uilabel(app.SlantCameraPanel);
            app.CrosshairWidthEditField_3Label.Text = 'Crosshair Width';
            app.CrosshairWidthEditFieldSlant = uieditfield(app.SlantCameraPanel, 'numeric');
            app.VideoDecimationEditField_3Label = uilabel(app.SlantCameraPanel);
            app.VideoDecimationEditField_3Label.Text = 'Video Decimation';
            app.VideoDecimationEditFieldSlant = uieditfield(app.SlantCameraPanel, 'numeric');
            app.ImageWidthEditField_3Label = uilabel(app.SlantCameraPanel);
            app.ImageWidthEditField_3Label.Text = 'Image Width';
            app.ImageWidthEditFieldSlant = uieditfield(app.SlantCameraPanel, 'numeric');
            app.CameraSettingsTab = uitab(app.TabGroup);
            app.CameraSettingsTab.Title = 'Camera Settings';
            app.DotPropertiesPanel = uipanel(app.CameraSettingsTab);
            app.DotPropertiesPanel.Title = 'Dot Properties';
            app.SideDotSeparationDistanceEditField_2Label = uilabel(app.DotPropertiesPanel);
            app.SideDotSeparationDistanceEditField_2Label.Text = 'Side Dot Separation Distance';
            app.SideDotSeparationDistanceEditField = uieditfield(app.DotPropertiesPanel, 'numeric');
            app.SideDotPositionVectorEditFieldLabel_2 = uilabel(app.DotPropertiesPanel);
            app.SideDotPositionVectorEditFieldLabel_2.Text = 'Side Dot Position Vector';
            app.SideDotPositionVectorEditFieldX = uieditfield(app.DotPropertiesPanel, 'numeric');
            app.BottomADotSeparationDistanceEditField_2Label = uilabel(app.DotPropertiesPanel);
            app.BottomADotSeparationDistanceEditField_2Label.Text = 'Bottom A Dot Separation Distance';
            app.BottomADotSeparationDistanceEditField = uieditfield(app.DotPropertiesPanel, 'numeric');
            app.BottomADotPositionVectorEditFieldLabel_2 = uilabel(app.DotPropertiesPanel);
            app.BottomADotPositionVectorEditFieldLabel_2.Text = 'Bottom A Dot Position Vector';
            app.BottomADotPositionVectorEditFieldX = uieditfield(app.DotPropertiesPanel, 'numeric');
            app.BottomBDotSeparationDistanceEditField_2Label = uilabel(app.DotPropertiesPanel);
            app.BottomBDotSeparationDistanceEditField_2Label.Text = 'Bottom B Dot Separation Distance';
            app.BottomBDotSeparationDistanceEditField = uieditfield(app.DotPropertiesPanel, 'numeric');
            app.BottomBDotPositionValuieEditFieldLabel_2 = uilabel(app.DotPropertiesPanel);
            app.BottomBDotPositionValuieEditFieldLabel_2.Text = 'Bottom B Dot Position Valuie';
            app.BottomBDotPositionValuieEditFieldX = uieditfield(app.DotPropertiesPanel, 'numeric');
            app.SideDotPositionVectorEditFieldY = uieditfield(app.DotPropertiesPanel, 'numeric');
            app.SideDotPositionVectorEditFieldZ = uieditfield(app.DotPropertiesPanel, 'numeric');
            app.BottomADotPositionVectorEditFieldY = uieditfield(app.DotPropertiesPanel, 'numeric');
            app.BottomADotPositionVectorEditFieldZ = uieditfield(app.DotPropertiesPanel, 'numeric');
            app.BottomBDotPositionValuieEditFieldY = uieditfield(app.DotPropertiesPanel, 'numeric');
            app.BottomBDotPositionValuieEditFieldZ = uieditfield(app.DotPropertiesPanel, 'numeric');
            app.CameraSpecificationsPanel = uipanel(app.CameraSettingsTab);
            app.CameraSpecificationsPanel.Title = 'Camera Specifications';
            app.CameraFOVEditFieldLabel_2 = uilabel(app.CameraSpecificationsPanel);
            app.CameraFOVEditFieldLabel_2.Text = 'Camera FOV';
            app.CameraFOVEditFieldHor = uieditfield(app.CameraSpecificationsPanel, 'numeric');
            app.CameraFOVEditFieldHor.ValueChangedFcn = createCallbackFcn(app, @CameraFOVEditFieldHorValueChanged, true);
            app.CameraFOVEditFieldVer = uieditfield(app.CameraSpecificationsPanel, 'numeric');
            app.CameraFOVEditFieldVer.ValueChangedFcn = createCallbackFcn(app, @CameraFOVEditFieldVerValueChanged, true);
            app.ImageErrorSettingsPanel = uipanel(app.CameraSettingsTab);
            app.ImageErrorSettingsPanel.Title = 'Image Error Settings';
            app.ThresholdEditField_2Label = uilabel(app.ImageErrorSettingsPanel);
            app.ThresholdEditField_2Label.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.ImageErrorSettingsPanel, 'numeric');
            app.DebounceTimeEditField_2Label = uilabel(app.ImageErrorSettingsPanel);
            app.DebounceTimeEditField_2Label.Text = 'Debounce Time';
            app.DebounceTimeEditField = uieditfield(app.ImageErrorSettingsPanel, 'numeric');
            app.CameraOrientationPanel = uipanel(app.CameraSettingsTab);
            app.CameraOrientationPanel.Title = 'Camera Orientation';
            app.SideCameraLabel2 = uilabel(app.CameraOrientationPanel);
            app.SideCameraLabel2.Text = 'Side Camera';
            app.EulerAngleEditFieldLabel_2 = uilabel(app.CameraOrientationPanel);
            app.EulerAngleEditFieldLabel_2.Text = 'Euler Angle';
            app.EulerAngleEditFieldSideRoll = uieditfield(app.CameraOrientationPanel, 'numeric');
            app.EulerAngleEditFieldSideRoll.ValueChangedFcn = createCallbackFcn(app, @EulerAngleEditFieldSideRollValueChanged, true);
            app.BottomCameraLabel = uilabel(app.CameraOrientationPanel);
            app.BottomCameraLabel.Text = 'Bottom Camera';
            app.EulerAngleEditField_2Label_2 = uilabel(app.CameraOrientationPanel);
            app.EulerAngleEditField_2Label_2.Text = 'Euler Angle';
            app.EulerAngleEditFieldBottomRoll = uieditfield(app.CameraOrientationPanel, 'numeric');
            app.EulerAngleEditFieldBottomPitch = uieditfield(app.CameraOrientationPanel, 'numeric');
            app.EulerAngleEditFieldBottomYaw = uieditfield(app.CameraOrientationPanel, 'numeric');
            app.PositionEditField_8Label_2 = uilabel(app.CameraOrientationPanel);
            app.PositionEditField_8Label_2.Text = 'Position';
            app.PositionEditFieldBottomX = uieditfield(app.CameraOrientationPanel, 'numeric');
            app.PositionEditFieldBottomY = uieditfield(app.CameraOrientationPanel, 'numeric');
            app.PositionEditFieldBottomZ = uieditfield(app.CameraOrientationPanel, 'numeric');
            app.SlantCameraLabel2 = uilabel(app.CameraOrientationPanel);
            app.SlantCameraLabel2.Text = 'Slant Camera';
            app.EulerAngleEditField_3Label_2 = uilabel(app.CameraOrientationPanel);
            app.EulerAngleEditField_3Label_2.Text = 'Euler Angle';
            app.EulerAngleEditFieldSlantRoll = uieditfield(app.CameraOrientationPanel, 'numeric');
            app.PositionEditField_11Label_2 = uilabel(app.CameraOrientationPanel);
            app.PositionEditField_11Label_2.Text = 'Position';
            app.PositionEditFieldSlantX = uieditfield(app.CameraOrientationPanel, 'numeric');
            app.PositionEditFieldSlantY = uieditfield(app.CameraOrientationPanel, 'numeric');
            app.EulerAngleEditFieldSlantPitch = uieditfield(app.CameraOrientationPanel, 'numeric');
            app.EulerAngleEditFieldSlantYaw = uieditfield(app.CameraOrientationPanel, 'numeric');
            app.PositionEditFieldSlantZ = uieditfield(app.CameraOrientationPanel, 'numeric');
            app.PositionEditField_16Label_2 = uilabel(app.CameraOrientationPanel);
            app.PositionEditField_16Label_2.Text = 'Position';
            app.PositionEditFieldSideX = uieditfield(app.CameraOrientationPanel, 'numeric');
            app.PositionEditFieldSideX.ValueChangedFcn = createCallbackFcn(app, @PositionEditFieldSideXValueChanged, true);
            app.EulerAngleEditFieldSidePitch = uieditfield(app.CameraOrientationPanel, 'numeric');
            app.EulerAngleEditFieldSidePitch.ValueChangedFcn = createCallbackFcn(app, @EulerAngleEditFieldSidePitchValueChanged, true);
            app.EulerAngleEditFieldSideYaw = uieditfield(app.CameraOrientationPanel, 'numeric');
            app.EulerAngleEditFieldSideYaw.ValueChangedFcn = createCallbackFcn(app, @EulerAngleEditFieldSideYawValueChanged, true);
            app.PositionEditFieldSideY = uieditfield(app.CameraOrientationPanel, 'numeric');
            app.PositionEditFieldSideY.ValueChangedFcn = createCallbackFcn(app, @PositionEditFieldSideYValueChanged, true);
            app.PositionEditFieldSideZ = uieditfield(app.CameraOrientationPanel, 'numeric');
            app.PositionEditFieldSideZ.ValueChangedFcn = createCallbackFcn(app, @PositionEditFieldSideZValueChanged, true);
            app.CommunicationTab = uitab(app.TabGroup);
            app.CommunicationTab.Title = 'Communication';
            app.UDPPTPConfigurationPanel = uipanel(app.CommunicationTab);
            app.UDPPTPConfigurationPanel.Title = 'UDP/PTP Configuration';
            app.PTPOffsetEditFieldLabel = uilabel(app.UDPPTPConfigurationPanel);
            app.PTPOffsetEditFieldLabel.Text = 'PTP Offset';
            app.PTPOffsetEditField = uieditfield(app.UDPPTPConfigurationPanel, 'numeric');
            app.SyncIntervalEditFieldLabel = uilabel(app.UDPPTPConfigurationPanel);
            app.SyncIntervalEditFieldLabel.Text = 'Sync Interval';
            app.SyncIntervalEditField = uieditfield(app.UDPPTPConfigurationPanel, 'numeric');
            app.Machine3Panel_2 = uipanel(app.CommunicationTab);
            app.Machine3Panel_2.Title = 'Machine 3';
            app.IPAddressEditFieldLabel = uilabel(app.Machine3Panel_2);
            app.IPAddressEditFieldLabel.Text = 'IP Address';
            app.IPAddressEditFieldMachine2 = uieditfield(app.Machine3Panel_2, 'numeric');
            app.PortNumberEditFieldLabel = uilabel(app.Machine3Panel_2);
            app.PortNumberEditFieldLabel.Text = 'Port Number';
            app.PortNumberEditFieldMachine2 = uieditfield(app.Machine3Panel_2, 'numeric');
            app.MinDelayEditFieldLabel = uilabel(app.Machine3Panel_2);
            app.MinDelayEditFieldLabel.Text = 'Min Delay';
            app.MinDelayEditFieldMachine2 = uieditfield(app.Machine3Panel_2, 'numeric');
            app.Machine4Panel_2 = uipanel(app.CommunicationTab);
            app.Machine4Panel_2.Title = 'Machine 4';
            app.IPAddressEditField_2Label = uilabel(app.Machine4Panel_2);
            app.IPAddressEditField_2Label.Text = 'IP Address';
            app.IPAddressEditFieldMachine3 = uieditfield(app.Machine4Panel_2, 'numeric');
            app.PortNumberEditField_2Label = uilabel(app.Machine4Panel_2);
            app.PortNumberEditField_2Label.Text = 'Port Number';
            app.PortNumberEditFieldMachine3 = uieditfield(app.Machine4Panel_2, 'numeric');
            app.MinDelayEditField_2Label = uilabel(app.Machine4Panel_2);
            app.MinDelayEditField_2Label.Text = 'Min Delay';
            app.MinDelayEditFieldMachine3 = uieditfield(app.Machine4Panel_2, 'numeric');
            app.Machine5Panel = uipanel(app.CommunicationTab);
            app.Machine5Panel.Title = 'Machine 5';
            app.IPAddressEditField_3Label = uilabel(app.Machine5Panel);
            app.IPAddressEditField_3Label.Text = 'IP Address';
            app.IPAddressEditFieldMachine4 = uieditfield(app.Machine5Panel, 'numeric');
            app.PortNumberEditField_3Label = uilabel(app.Machine5Panel);
            app.PortNumberEditField_3Label.Text = 'Port Number';
            app.PortNumberEditFieldMachine4 = uieditfield(app.Machine5Panel, 'numeric');
            app.MinDelayEditField_3Label = uilabel(app.Machine5Panel);
            app.MinDelayEditField_3Label.Text = 'Min Delay';
            app.MinDelayEditFieldMachine4 = uieditfield(app.Machine5Panel, 'numeric');
            app.Machine2Panel = uipanel(app.CommunicationTab);
            app.Machine2Panel.Title = 'Machine 2';
            app.AnnounceIntervalEditFieldLabel = uilabel(app.Machine2Panel);
            app.AnnounceIntervalEditFieldLabel.Text = 'Announce Interval';
            app.AnnounceIntervalEditField = uieditfield(app.Machine2Panel, 'numeric');
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end